classdef MRVS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MobileRobotControlMenuUIFigure  matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        searchButton                    matlab.ui.control.Button
        goButton                        matlab.ui.control.Button
        MessageBoxTextAreaLabel         matlab.ui.control.Label
        MessageBoxTextArea              matlab.ui.control.TextArea
        DistancecmTextAreaLabel         matlab.ui.control.Label
        DistancecmTextArea              matlab.ui.control.TextArea
        connectButton                   matlab.ui.control.Button
        disconnectButton                matlab.ui.control.Button
        RotateCcwButton                 matlab.ui.control.Button
        RotateCwButton                  matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        connectLamp                     matlab.ui.control.Lamp
        COMEditFieldLabel               matlab.ui.control.Label
        COMEditField                    matlab.ui.control.EditField
        UIAxesLeft                      matlab.ui.control.UIAxes
        StopButton                      matlab.ui.control.Button
        ConnectingCamerasButton         matlab.ui.control.Button
        DisconnectingCamerasButton      matlab.ui.control.Button
        AdjustPositionButton            matlab.ui.control.Button
        UIAxesRight                     matlab.ui.control.UIAxes
        XButton                         matlab.ui.control.Button
        ManualControlPanel              matlab.ui.container.Panel
        forwardButton                   matlab.ui.control.Button
        rightButton                     matlab.ui.control.Button
        leftButton                      matlab.ui.control.Button
        backwardButton                  matlab.ui.control.Button
        LeftCamEditFieldLabel           matlab.ui.control.Label
        LeftCamEditField                matlab.ui.control.NumericEditField
        RightCamEditFieldLabel          matlab.ui.control.Label
        RightCamEditField               matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        ExposureTab                     matlab.ui.container.Tab
        RightCamSliderLabel             matlab.ui.control.Label
        RightCamSlider                  matlab.ui.control.Slider
        LeftCamSliderLabel              matlab.ui.control.Label
        LeftCamSlider                   matlab.ui.control.Slider
        BrightnessTab                   matlab.ui.container.Tab
        LeftCamSlider_2Label            matlab.ui.control.Label
        LeftCamSlider_2                 matlab.ui.control.Slider
        RightCamSlider_2Label           matlab.ui.control.Label
        RightCamSlider_2                matlab.ui.control.Slider
    end

    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function straightAhead(app)
            global a;
            global r1; global r2; global l1; global l2;
            try
                writeDigitalPin(a,r1,1);
                writeDigitalPin(a,r2,0);
                writeDigitalPin(a,l1,1);
                writeDigitalPin(a,l2,0);
            catch
                app.MessageBoxTextArea.Value='Please Connect to Arduino';
            end
        end
        
        function turnClockwise(app)
            global a;
            global r1; global r2; global l1; global l2;
            try
                writeDigitalPin(a,r1,0);
                writeDigitalPin(a,r2,1);
                writeDigitalPin(a,l1,1);
                writeDigitalPin(a,l2,0);
            catch
                app.MessageBoxTextArea.Value='Please Connect to Arduino';
            end
            
        end
        
        function turnCounterClockwise(app)
            global a;
            global r1; global r2; global l1; global l2;
            try
                writeDigitalPin(a,r1,1);
                writeDigitalPin(a,r2,0);
                writeDigitalPin(a,l1,0);
                writeDigitalPin(a,l2,1);
            catch
                app.MessageBoxTextArea.Value='Please Connect to Arduino';
            end
            
        end
        
        function reverse(app)
            global a;
            global r1; global r2; global l1; global l2;
            try
                writeDigitalPin(a,r1,0);
                writeDigitalPin(a,r2,1);
                writeDigitalPin(a,l1,0);
                writeDigitalPin(a,l2,1);
            catch
                app.MessageBoxTextArea.Value='Please Connect to Arduino';
            end
            
        end
        
        function stopBoy(app)
            global a;
            global r1; global r2; global l1; global l2;
            try
                writeDigitalPin(a,r1,0);
                writeDigitalPin(a,r2,0);
                writeDigitalPin(a,l1,0);
                writeDigitalPin(a,l2,0);
            catch
                app.MessageBoxTextArea.Value='Please Connect to Arduino';
            end
        end
        
        
        function searchGo(app)
            global distance;
            global oldDistance;
            
            java.lang.Thread.sleep(1000)
            searchButtonPushed(app);
            java.lang.Thread.sleep(1500);
            
            AdjustPositionButtonPushed(app);
            java.lang.Thread.sleep(1000);
            
            searchButtonPushed(app);
            java.lang.Thread.sleep(1500);
            
            if distance > oldDistance
                java.lang.Thread.sleep(1000)
                searchButtonPushed(app);
                java.lang.Thread.sleep(1500);
                
                AdjustPositionButtonPushed(app);
                java.lang.Thread.sleep(1000);
                
                searchButtonPushed(app);
                java.lang.Thread.sleep(1500);
                
                if distance > oldDistance
                    
                    
                    app.MessageBoxTextArea.Value='There is an error, please search again';
                else
                    
                    goButtonPushed(app);
                    java.lang.Thread.sleep(1000);
                    searchButtonPushed(app);
                    java.lang.Thread.sleep(1500);
                    goButtonPushed(app);
                    
                end
            else
                goButtonPushed(app);
                java.lang.Thread.sleep(1000);
                searchButtonPushed(app);
                java.lang.Thread.sleep(1500);
                goButtonPushed(app);
            end
            
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            delete(instrfind({'Port'},{'COM4'}));
            global cam1;
            global cam2;
            global a;
            delete(cam1); delete(cam2);
            clear cam1; clear cam2; clear a;
            
            global blankPic;
            blankPic=imread('blank.jpg');
            
            
            imshow(blankPic, 'parent', app.UIAxes);
            imshow(blankPic, 'parent', app.UIAxes2);
            imshow(blankPic, 'parent', app.UIAxes3);
            imshow(blankPic, 'parent', app.UIAxes4);
            imshow(blankPic, 'parent', app.UIAxesLeft);
            imshow(blankPic, 'parent', app.UIAxesRight);
            
            app.connectLamp.Color='black';
            cd 'C:\Users\ibrahim\Desktop';
            
        end

        % Button pushed function: connectButton
        function connectButtonPushed(app, event)
            %load('calibrationSession5.mat');
            global a; global sensor;
            global r1;global r2;global l1;global l2;
            global m1;global m2; global m3; global m4;
            global trigPin; global echoPin;
            comVariable= app.COMEditField.Value;
            a = arduino(comVariable, 'Uno', 'Libraries', 'JRodrigoTech/HCSR04');
            app.connectLamp.Color='green';


            % Right-hand side motors:
            r1='D2';
            r2='D4';
            % Left-hand side motors:
            l1='D7';
            l2='D8';
            
            % Analog signal for motors speed:
            m1='D10';
            m2='D3';
            m3='D6';
            m4='D9';
            
            % Ultrasonic code:
            trigPin = 'D11';    % Trigger
            echoPin = 'D12';    % Echo
            
            
            sensor = addon(a, 'JRodrigoTech/HCSR04', trigPin, echoPin);
            
            
            configurePin(a,r1, 'DigitalOutput');
            configurePin(a,r2, 'DigitalOutput');
            configurePin(a,l1, 'DigitalOutput');
            configurePin(a,l2, 'DigitalOutput');
            configurePin(a,m1, 'DigitalOutput');
            configurePin(a,m2, 'DigitalOutput');
            configurePin(a,m3, 'DigitalOutput');
            configurePin(a,m4, 'DigitalOutput');


            %             configurePin(a,trigPin, 'DigitalOutput');
            %             configurePin(a,echoPin, 'DigitalInput');
            
            writePWMVoltage(a,m1,(255/255)*5);
            writePWMVoltage(a,m2,(245/255)*5);
            writePWMVoltage(a,m3,(245/255)*5);
            writePWMVoltage(a,m4,(255/255)*5);


        end

        % Callback function
        function DisconnectButtonPushed(app, event)
            app.connectLamp.Color='black';
            delete(instrfind({'Port'},{'COM4'}));
            clear
            
        end

        % Button pushed function: searchButton
        function searchButtonPushed(app, event)
            global cam1; global cam2;
            % global a; global r1;global r2;global l1;global l2;
            global x;
            global distance;
            global blankPic;
            
            try
                img = snapshot(cam1);
                img2 = snapshot(cam2);
                imwrite(img,'picLeft.jpg');
                imwrite(img2,'picRight.jpg')
                
            catch
                app.MessageBoxTextArea.Value='Cameras are not connected, connect to cameras Please.';
            end


            J1=imread('picLeft.jpg');
            J2=imread('picRight.jpg');
            J1=imrotate(J1,90);
            J2=imrotate(J2,90);
            
            load('calibrationSession5.mat');
            [J1,J2] = rectifyStereoImages(J1,J2,calibrationSession.CameraParameters);
            
            I1=rgb2gray(J1);
            I2=rgb2gray(J2);
            
            
            J1=imgaussfilt(I1,1.2);
            J2=imgaussfilt(I2,1.2);
            J1=255-J1;
            J2=255-J2;
            
            try
                %K1=255-I1;
                [Center1,Radius1]=imfindcircles(I1,[10 150],'ObjectPolarity',...
                    'dark', 'Sensitivity',0.85);
                
                Center1=Center1(1,:);
                Radius1=Radius1(1,:);
                
            catch
                answer='No Circle Found!';
                app.MessageBoxTextArea.Value=answer;
                app.DistancecmTextArea.Value=' ';
            end
            
            try
                %K2=255-I2;
                [Center2,Radius2]=imfindcircles(I2,[10 150],'ObjectPolarity',...
                    'dark', 'Sensitivity',0.85);
                
                Center2=Center2(1,:);
                Radius2=Radius2(1,:);
                
            catch
                answer='No Circle Found!';
                app.MessageBoxTextArea.Value=answer;
                app.DistancecmTextArea.Value=' ';
            end
            
            if mean(Radius1) > 0 && mean(Radius2) > 0
                
                disparityRange = [0 128]; % arbitrary number and divisable by 8, max = 128
                disparityMap = disparity(J1,J2,'BlockSize', 15,'DisparityRange',disparityRange);
                
                %disparity image
                imshow(disparityMap,disparityRange, 'parent', app.UIAxes4);
                answer='Circle Found!';
                app.MessageBoxTextArea.Value=answer;
                try
                    
                    
                    theCenter=(Center1+Center2)/2;
                    
                    x=floor(theCenter(1));
                    y=floor(theCenter(2));
                    
                    
                    f=846;
                    b=10;
                    
                    distance=f*b/disparityMap(y,x);
                    
                    if distance < 40
                        distance=f*b/disparityMap(y,x-10);
                    end
                    if distance < 40
                        distance=f*b/disparityMap(y,x-20);
                    end
                    if distance < 40
                        distance=f*b/disparityMap(y,x-30);
                    end
                    if distance < 40
                        distance=f*b/disparityMap(y,x-40);
                    end
                    if distance < 40
                        distance=f*b/disparityMap(y,x-50);
                    end


                    %errorFactor=distance*0.136; %initial error factor
                    %distanceRead=distance+errorFactor;
                    distanceRead=distance;
                    
                    %distance=1.34*distanceRead-35.4; % error equation
                    %distance=1.14*distanceRead+-30.967; % error equation
                    %distance=0.0021*distanceRead^2+0.727*distanceRead+5.48;
                    
                    distance=0.0016*distanceRead^2+0.87*distanceRead+3.4;
                    
                    %showing messages:
                    distanceStr=num2str(distance);
                    app.DistancecmTextArea.Value=distanceStr;
                    
                    if distance < 0
                        app.DistancecmTextArea.Value=' ';
                        app.MessageBoxTextArea.Value='Try again :)';
                        
                    end
                catch
                    answer='No Circle Found!';
                    app.MessageBoxTextArea.Value=answer;
                    app.DistancecmTextArea.Value=' ';
                    
                    imshow(blankPic, 'parent', app.UIAxes);
                    imshow(blankPic, 'parent', app.UIAxes2);
                    imshow(blankPic, 'parent', app.UIAxes3);
                    imshow(blankPic, 'parent', app.UIAxes4);
                    
                end
            else
                answer='No Circle Found!';
                app.MessageBoxTextArea.Value=answer;
                app.DistancecmTextArea.Value=' ';
                
                imshow(blankPic, 'parent', app.UIAxes);
                imshow(blankPic, 'parent', app.UIAxes2);
                imshow(blankPic, 'parent', app.UIAxes3);
                imshow(blankPic, 'parent', app.UIAxes4);
                
                
            end
            
            %showing proper images:
            
            %left
            imshow(I1, 'parent', app.UIAxes);
            
            
            %right
            imshow(I2, 'parent', app.UIAxes2);
            
            
            %both
            imshow(stereoAnaglyph(I1,I2), 'parent',app.UIAxes3)
            
            java.lang.Thread.sleep(100);


        end

        % Button pushed function: goButton
        function goButtonPushed(app, event)
            global sensor;
            global sensorDistance;
            global distance;
            global cam1;
            global cam2;
            global blankPic;
            global oldDistance;
            
            oldDistance=distance;
            obstacleExist=0;
            obstacleDistance=10;
            
            velocity= 20.387 ; %cm/sec
            distanceReal=distance-60;
            if distance==0
                distanceReal=0;
            end
            
            time = abs(distanceReal/velocity); %time needed to get there in seconds
            
            if distance==0
                app.MessageBoxTextArea.Value='Press the Search button first.';
            end
            
            
            if floor(time)>=4 && floor(time)<=6
                n=2;
            elseif floor(time)>6 && floor(time)<=9
                n=4;
            elseif floor(time)>9 && floor(time)<=14
                n=6;
            elseif floor(time)>14
                n=8;
            else
                n=1;
            end


            for i=1:n
                
                
                if time==0
                    break;
                end
                
                straightAhead(app);
                t1=now;
                
                t=0;
                while t < time/n
                    sensorDistance = readDistance(sensor);
                    sensorDistance=sensorDistance*100;
                    
                    if sensorDistance <= obstacleDistance
                        %avoid function:
                        
                        app.MessageBoxTextArea.Value='Obstacle is found! Avoiding now.';
                        
                        stopBoy(app);                    %stopping
                        java.lang.Thread.sleep(100);
                        
                        turnCounterClockwise(app);        %1) turning left
                        java.lang.Thread.sleep(1000);
                        
                        stopBoy(app);                        %stopping
                        java.lang.Thread.sleep(100);
                        
                        straightAhead(app);                 %2) going straight
                        java.lang.Thread.sleep(1500);
                        
                        stopBoy(app);                        %stopping
                        java.lang.Thread.sleep(100);
                        
                        turnClockwise(app);                   %3) turning right
                        java.lang.Thread.sleep(1000);
                        
                        stopBoy(app);                        %stopping
                        java.lang.Thread.sleep(100);
                        
                        straightAhead(app);                 %4) going straight
                        java.lang.Thread.sleep(3000);
                        
                        stopBoy(app);                        %stopping
                        java.lang.Thread.sleep(100);
                        
                        turnClockwise(app);                %5) turning right
                        java.lang.Thread.sleep(1100);
                        
                        stopBoy(app);                        %stopping
                        java.lang.Thread.sleep(100);
                        
                        straightAhead(app);                 %6) going straight
                        java.lang.Thread.sleep(1500);
                        
                        stopBoy(app);                        %stopping
                        java.lang.Thread.sleep(100);
                        
                        turnCounterClockwise(app);        %7) turning left
                        java.lang.Thread.sleep(1150);
                        
                        stopBoy(app);                        %stopping
                        java.lang.Thread.sleep(100);
                        obstacleExist=1;
                        break; %break while
                    end %end if
                    
                    t2=now;
                    t=t2-t1;
                    t=t*60*60*24;
                    
                end %end while
                
                if distance==0
                    obstacleExist=2; %will need it after the for loop
                    %                 and has nothing to do with obstacles.
                    
                    break; %break for
                    
                end
                
                
                app.MessageBoxTextArea.Value='Following circle.';
                stopBoy(app);
                
                
                if obstacleExist ==1
                    app.MessageBoxTextArea.Value='Obstacle Avoided.';
                    break; %break for
                    
                end


                %here was the search code;
                searchButtonPushed(app);


            end     % end of for
            
            if obstacleExist == 1
                distance=0;
                %app.MessageBoxTextArea.Value='Search again please :>';
                searchGo(app);
                %searchButtonPushed(app, event);
                %java.lang.Thread.sleep(7000);
                %goButtonPushed(app, event);
                
            elseif obstacleExist==2
                app.MessageBoxTextArea.Value='Press the Search button first.';
                app.DistancecmTextArea.Value=' ';
                
            else
                
                answer='We are here!';
                app.MessageBoxTextArea.Value=answer;
                app.DistancecmTextArea.Value=' ';
                distance=0;
                
            end
            
            
        end

        % Button pushed function: RotateCcwButton
        function RotateCcwButtonPushed(app, event)
            %             global a;
            %             global r1; global r2; global l1; global l2;
            
            turnCounterClockwise(app);
            java.lang.Thread.sleep(250);
            
            stopBoy(app);
            
        end

        % Button pushed function: RotateCwButton
        function RotateCwButtonPushed(app, event)
            %             global a;
            %             global r1; global r2; global l1; global l2;
            
            turnClockwise(app);
            java.lang.Thread.sleep(250);
            
            stopBoy(app);
        end

        % Button pushed function: ConnectingCamerasButton
        function ConnectingCamerasButtonPushed(app, event)
            global cam1; global cam2;
            
            L= app.LeftCamEditField.Value;
            R=app.RightCamEditField.Value;
            
            cam1=webcam(L);
            cam2=webcam(R);
            
            value1 = app.LeftCamSlider.Value; %setting exposure of cam1
            cam1.Exposure=value1;
            
            value2 = app.RightCamSlider.Value; %setting expoure of cam2
            cam2.Exposure=value2;
            
            frame1=snapshot(cam1);
            frame2=snapshot(cam2);
            imwrite(frame1,'livefeedLeft.jpg');
            imwrite(frame2,'livefeedRight.jpg');
            
            frame1=imread('livefeedLeft.jpg');
            frame2=imread('livefeedRight.jpg');
            
            frame1=imrotate(frame1,90);
            frame2=imrotate(frame2,90);
            
            %load('calibrationSession5.mat');
            %[frame1,frame2] = rectifyStereoImages(frame1,frame2,calibrationSession.CameraParameters);
            
            im1=image(app.UIAxesLeft, zeros(size(frame1),'uint8'));
            axis(app.UIAxesLeft,'image');
            im2=image(app.UIAxesRight, zeros(size(frame2),'uint8'));
            axis(app.UIAxesRight,'image');


            preview(cam1,im1);
            preview(cam2,im2);
            
            
        end

        % Button pushed function: DisconnectingCamerasButton
        function DisconnectingCamerasButtonPushed(app, event)
            global cam1;
            global cam2;
            global blankPic;
            
            delete(cam1); delete(cam2);
            clear cam1; clear cam2
            
            imshow(blankPic, 'parent', app.UIAxesLeft);
            imshow(blankPic, 'parent', app.UIAxesRight);
            
            
        end

        % Button pushed function: AdjustPositionButton
        function AdjustPositionButtonPushed(app, event)
            global x;
            if x < 157
                delay=157-x;
                turnCounterClockwise(app);
                java.lang.Thread.sleep(delay);
                stopBoy(app);
                
            end
            
            if x > 217
                delay=x-217;
                turnClockwise(app);
                java.lang.Thread.sleep(delay);
                stopBoy(app);
            end
            
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stopBoy(app);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            close all force;
        end

        % Close request function: MobileRobotControlMenuUIFigure
        function MobileRobotControlMenuUIFigureCloseRequest(app, event)
            delete(app);
            
        end

        % Button pushed function: forwardButton
        function forwardButtonPushed(app, event)
            straightAhead(app);
            java.lang.Thread.sleep(75);
            stopBoy(app);
        end

        % Button pushed function: rightButton
        function rightButtonPushed(app, event)
            turnClockwise(app);
            java.lang.Thread.sleep(75);
            stopBoy(app);
        end

        % Button pushed function: backwardButton
        function backwardButtonPushed(app, event)
            reverse(app);
            java.lang.Thread.sleep(75);
            stopBoy(app);
        end

        % Button pushed function: leftButton
        function leftButtonPushed(app, event)
            turnCounterClockwise(app);
            java.lang.Thread.sleep(75);
            stopBoy(app);
        end

        % Value changed function: RightCamSlider
        function RightCamSliderValueChanged(app, event)
            global cam2;
            
            value2 = app.RightCamSlider.Value;
            %value2=floor(value2);
            cam2.Exposure=value2;
        end

        % Value changed function: LeftCamSlider
        function LeftCamSliderValueChanged(app, event)
            global cam1;
            
            value1 = app.LeftCamSlider.Value;
            %value1=floor(value1);
            cam1.Exposure=value1;
        end

        % Value changed function: LeftCamSlider_2
        function LeftCamSlider_2ValueChanged(app, event)
            global cam1;
            
            value1 = app.LeftCamSlider_2.Value;
            %value1=floor(value1);
            cam1.Brightness=value1;
        end

        % Value changed function: RightCamSlider_2
        function RightCamSlider_2ValueChanged(app, event)
            global cam2;
            
            value2 = app.RightCamSlider_2.Value;
            %value2=floor(value2);
            cam2.Brightness=value2;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MobileRobotControlMenuUIFigure = uifigure;
            app.MobileRobotControlMenuUIFigure.Name = 'Mobile Robot Control Menu';
            app.MobileRobotControlMenuUIFigure.CloseRequestFcn = createCallbackFcn(app, @MobileRobotControlMenuUIFigureCloseRequest, true);
            setAutoResize(app, app.MobileRobotControlMenuUIFigure, false)
            app.UIAxes = uiaxes(app.MobileRobotControlMenuUIFigure);
            title(app.UIAxes, 'Left Image');
            app.UIAxes2 = uiaxes(app.MobileRobotControlMenuUIFigure);
            title(app.UIAxes2, 'Right Image');
            app.UIAxes3 = uiaxes(app.MobileRobotControlMenuUIFigure);
            title(app.UIAxes3, 'Both');
            app.UIAxes4 = uiaxes(app.MobileRobotControlMenuUIFigure);
            title(app.UIAxes4, 'Disparity');
            app.searchButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.searchButton.ButtonPushedFcn = createCallbackFcn(app, @searchButtonPushed, true);
            app.searchButton.Icon = 'searchIcon.png';
            app.searchButton.Text = '';
            app.goButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.goButton.Icon = 'go.png';
            app.goButton.Text = '';
            app.MessageBoxTextAreaLabel = uilabel(app.MobileRobotControlMenuUIFigure);
            app.MessageBoxTextAreaLabel.Text = 'Message Box';
            app.MessageBoxTextArea = uitextarea(app.MobileRobotControlMenuUIFigure);
            app.DistancecmTextAreaLabel = uilabel(app.MobileRobotControlMenuUIFigure);
            app.DistancecmTextAreaLabel.Text = 'Distance (cm)';
            app.DistancecmTextArea = uitextarea(app.MobileRobotControlMenuUIFigure);
            app.connectButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.connectButton.ButtonPushedFcn = createCallbackFcn(app, @connectButtonPushed, true);
            app.connectButton.Icon = 'connect.png';
            app.connectButton.Text = '';
            app.disconnectButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.disconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.disconnectButton.Icon = 'disconnect.png';
            app.disconnectButton.Text = '';
            app.RotateCcwButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.RotateCcwButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCcwButtonPushed, true);
            app.RotateCcwButton.Icon = 'ccw.png';
            app.RotateCcwButton.Text = '';
            app.RotateCwButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.RotateCwButton.ButtonPushedFcn = createCallbackFcn(app, @RotateCwButtonPushed, true);
            app.RotateCwButton.Icon = 'cw.png';
            app.RotateCwButton.Text = '';
            app.Label = uilabel(app.MobileRobotControlMenuUIFigure);
            app.Label.Text = '';
            app.connectLamp = uilamp(app.MobileRobotControlMenuUIFigure);
            app.COMEditFieldLabel = uilabel(app.MobileRobotControlMenuUIFigure);
            app.COMEditFieldLabel.Text = 'COM:';
            app.COMEditField = uieditfield(app.MobileRobotControlMenuUIFigure, 'text');
            app.COMEditField.Value = 'COM4';
            app.UIAxesLeft = uiaxes(app.MobileRobotControlMenuUIFigure);
            title(app.UIAxesLeft, 'Left Cam View');
            app.StopButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ConnectingCamerasButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.ConnectingCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectingCamerasButtonPushed, true);
            app.ConnectingCamerasButton.Text = {'Connecting'; 'Cameras'};
            app.DisconnectingCamerasButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.DisconnectingCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectingCamerasButtonPushed, true);
            app.DisconnectingCamerasButton.Text = {'Disconnecting'; 'Cameras'};
            app.AdjustPositionButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.AdjustPositionButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustPositionButtonPushed, true);
            app.AdjustPositionButton.Text = {'Adjust'; 'Position'};
            app.UIAxesRight = uiaxes(app.MobileRobotControlMenuUIFigure);
            title(app.UIAxesRight, 'Right Cam View');
            app.XButton = uibutton(app.MobileRobotControlMenuUIFigure, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.ManualControlPanel = uipanel(app.MobileRobotControlMenuUIFigure);
            app.ManualControlPanel.Title = 'Manual Control';
            app.forwardButton = uibutton(app.ManualControlPanel, 'push');
            app.forwardButton.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forwardButton.Icon = 'forward.png';
            app.forwardButton.Text = '';
            app.rightButton = uibutton(app.ManualControlPanel, 'push');
            app.rightButton.ButtonPushedFcn = createCallbackFcn(app, @rightButtonPushed, true);
            app.rightButton.Icon = 'right.png';
            app.rightButton.Text = '';
            app.leftButton = uibutton(app.ManualControlPanel, 'push');
            app.leftButton.ButtonPushedFcn = createCallbackFcn(app, @leftButtonPushed, true);
            app.leftButton.Icon = 'left.png';
            app.leftButton.Text = '';
            app.backwardButton = uibutton(app.ManualControlPanel, 'push');
            app.backwardButton.ButtonPushedFcn = createCallbackFcn(app, @backwardButtonPushed, true);
            app.backwardButton.Icon = 'backward.png';
            app.backwardButton.Text = '';
            app.LeftCamEditFieldLabel = uilabel(app.MobileRobotControlMenuUIFigure);
            app.LeftCamEditFieldLabel.Text = 'Left Cam';
            app.LeftCamEditField = uieditfield(app.MobileRobotControlMenuUIFigure, 'numeric');
            app.LeftCamEditField.Value = 1;
            app.RightCamEditFieldLabel = uilabel(app.MobileRobotControlMenuUIFigure);
            app.RightCamEditFieldLabel.Text = 'Right Cam';
            app.RightCamEditField = uieditfield(app.MobileRobotControlMenuUIFigure, 'numeric');
            app.RightCamEditField.Value = 3;
            app.TabGroup = uitabgroup(app.MobileRobotControlMenuUIFigure);
            app.ExposureTab = uitab(app.TabGroup);
            app.ExposureTab.Title = 'Exposure';
            app.ExposureTab.Units = 'pixels';
            app.RightCamSliderLabel = uilabel(app.ExposureTab);
            app.RightCamSliderLabel.Text = {'Right'; 'Cam'};
            app.RightCamSlider = uislider(app.ExposureTab);
            app.RightCamSlider.Limits = [-9 0];
            app.RightCamSlider.MajorTicks = [-9 -8 -7 -6 -5 -4 -3 -2 -1 0];
            app.RightCamSlider.ValueChangedFcn = createCallbackFcn(app, @RightCamSliderValueChanged, true);
            app.RightCamSlider.MinorTicks = [];
            app.RightCamSlider.Value = -4;
            app.LeftCamSliderLabel = uilabel(app.ExposureTab);
            app.LeftCamSliderLabel.Text = {'Left'; 'Cam'};
            app.LeftCamSlider = uislider(app.ExposureTab);
            app.LeftCamSlider.Limits = [-9 0];
            app.LeftCamSlider.MajorTicks = [-9 -8 -7 -6 -5 -4 -3 -2 -1 0];
            app.LeftCamSlider.ValueChangedFcn = createCallbackFcn(app, @LeftCamSliderValueChanged, true);
            app.LeftCamSlider.MinorTicks = [];
            app.LeftCamSlider.Value = -4;
            app.BrightnessTab = uitab(app.TabGroup);
            app.BrightnessTab.Title = 'Brightness';
            app.BrightnessTab.Units = 'pixels';
            app.LeftCamSlider_2Label = uilabel(app.BrightnessTab);
            app.LeftCamSlider_2Label.Text = {'Left'; 'Cam'};
            app.LeftCamSlider_2 = uislider(app.BrightnessTab);
            app.LeftCamSlider_2.Limits = [0 255];
            app.LeftCamSlider_2.ValueChangedFcn = createCallbackFcn(app, @LeftCamSlider_2ValueChanged, true);
            app.LeftCamSlider_2.Value = 109;
            app.RightCamSlider_2Label = uilabel(app.BrightnessTab);
            app.RightCamSlider_2Label.Text = {'Right'; 'Cam'};
            app.RightCamSlider_2 = uislider(app.BrightnessTab);
            app.RightCamSlider_2.Limits = [0 255];
            app.RightCamSlider_2.ValueChangedFcn = createCallbackFcn(app, @RightCamSlider_2ValueChanged, true);
            app.RightCamSlider_2.Value = 109;
        end
    end

    methods (Access = public)

        % Construct app
        function app = MRVS()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MobileRobotControlMenuUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MobileRobotControlMenuUIFigure)
        end
    end
end